classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EMERGENCYSTOPButton            matlab.ui.control.Button
        q1SliderLabel                  matlab.ui.control.Label
        q1Slider                       matlab.ui.control.Slider
        q2SliderLabel                  matlab.ui.control.Label
        q2Slider                       matlab.ui.control.Slider
        q3SliderLabel                  matlab.ui.control.Label
        q3Slider                       matlab.ui.control.Slider
        q5SliderLabel                  matlab.ui.control.Label
        q5Slider                       matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        ZSlider                        matlab.ui.control.Slider
        SelectShapetoDrawListBoxLabel  matlab.ui.control.Label
        SelectShapetoDrawListBox       matlab.ui.control.ListBox
        PerspexonFrameSwitchLabel      matlab.ui.control.Label
        PerspexonFrameSwitch           matlab.ui.control.Switch
        TEACHJOINTSLabel               matlab.ui.control.Label
        TEACHPOSITIONLabel             matlab.ui.control.Label
        ScaleOfShapeSliderLabel        matlab.ui.control.Label
        ScaleOfShapeSlider             matlab.ui.control.Slider
        CollisionModeSwitchLabel       matlab.ui.control.Label
        CollisionModeSwitch            matlab.ui.control.Switch
        STARTButton                    matlab.ui.control.Button
        SELECTMODESLabel               matlab.ui.control.Label
        SELECTSHAPELabel               matlab.ui.control.Label
        STARTDRAWINGLabel              matlab.ui.control.Label
        PLOTSIMButton                  matlab.ui.control.Button
        PLOTSIMULATIONLabel            matlab.ui.control.Label
        TESTdobot_qButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        dobot; % Dobot model
        environment; % Environment model
        pencil; % Pencil Model
        
        dobotBase = transl(0,0,0);
        paperBase = transl(0.2875,0,0);
        pencilBase = transl(0,-0.25,0.06);
    end
    

    methods (Access = private)

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            fprintf('Emergency Stop has been pressed \n \n');
        end

        % Button pushed function: PLOTSIMButton
        function PLOTSIMButtonPushed(app, event)
            fprintf('Mode selected: \n');
            fprintf('The Perspex is: %s \n',app.PerspexonFrameSwitch.Value);
            fprintf('Collision mode is: %s \n \n',app.CollisionModeSwitch.Value);
            
            if app.PerspexonFrameSwitch.Value == "Off"
                perspexOn = false;
            else
                perspexOn = true;
            end
            
            app.dobot = Dobot(app.dobotBase);
            app.environment = Environment(app.paperBase, app.pencilBase, perspexOn);
            app.pencil = Pencil(app.pencilBase);
            axis equal;
        end

        % Button pushed function: TESTdobot_qButton
        function TESTdobot_qButtonPushed(app, event)
            fprintf('Testing dobot_q\n');
            
            dobot = Dobot(transl(0,0,0));
            load('dobot_q.mat');
            dobot.model.animate(dobot.CalcJointAngles(dobot_q));
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fprintf('Start Button Pressed\n\n');
            if app.CollisionModeSwitch.Value == "Off"
                % Pick up Pencil
                fprintf('Picking Up Pencil\n\n');
                app.dobot.PickPencil(app.pencil);
                
                fprintf('Centralising Pencil Over Paper\n\n');
                app.dobot.CentrePencilOverPaper(app.environment, app.pencil);
                
                fprintf('Calculating Edges of Shape\n\n');
                app.dobot.planCornersOfShape(app.SelectShapetoDrawListBox.Value, app.ScaleOfShapeSlider.Value);
                
                fprintf('Drawing Shape\n\n');
                app.dobot.DrawShapeOnPaper(app.pencil);
                
                fprintf('Return Pencil\n\n');
                app.dobot.ReturnPencil(app.pencil);
            else
                fprintf('Moving Until just before Collision with object\n')
                app.dobot.CollisionMode();
                fprintf('Collision Detection Complete. \nClose Simulation to Continue\n\n');
            end   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-90 90];
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [0 85];
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-10 95];
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-90 90];
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 0.35];
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-0.35 0.35];
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-0.1 0.5];
            app.SelectShapetoDrawListBoxLabel = uilabel(app.UIFigure);
            app.SelectShapetoDrawListBoxLabel.Text = {'Select Shape'; 'to Draw'};
            app.SelectShapetoDrawListBox = uilistbox(app.UIFigure);
            app.SelectShapetoDrawListBox.Items = {'Square', 'Triangle'};
            app.SelectShapetoDrawListBox.Value = 'Square';
            app.PerspexonFrameSwitchLabel = uilabel(app.UIFigure);
            app.PerspexonFrameSwitchLabel.Text = 'Perspex on Frame';
            app.PerspexonFrameSwitch = uiswitch(app.UIFigure, 'slider');
            app.TEACHJOINTSLabel = uilabel(app.UIFigure);
            app.TEACHJOINTSLabel.Text = 'TEACH JOINTS';
            app.TEACHPOSITIONLabel = uilabel(app.UIFigure);
            app.TEACHPOSITIONLabel.Text = 'TEACH POSITION';
            app.ScaleOfShapeSliderLabel = uilabel(app.UIFigure);
            app.ScaleOfShapeSliderLabel.Text = {'Scale Of'; 'Shape'};
            app.ScaleOfShapeSlider = uislider(app.UIFigure);
            app.ScaleOfShapeSlider.Limits = [1 2];
            app.ScaleOfShapeSlider.Value = 1;
            app.CollisionModeSwitchLabel = uilabel(app.UIFigure);
            app.CollisionModeSwitchLabel.Text = 'Collision Mode';
            app.CollisionModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SELECTMODESLabel = uilabel(app.UIFigure);
            app.SELECTMODESLabel.Text = '1) SELECT  MODE''S';
            app.SELECTSHAPELabel = uilabel(app.UIFigure);
            app.SELECTSHAPELabel.Text = '3) SELECT  SHAPE';
            app.STARTDRAWINGLabel = uilabel(app.UIFigure);
            app.STARTDRAWINGLabel.Text = '4) START DRAWING';
            app.PLOTSIMButton = uibutton(app.UIFigure, 'push');
            app.PLOTSIMButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTSIMButtonPushed, true);
            app.PLOTSIMButton.Text = 'PLOT SIM';
            app.PLOTSIMULATIONLabel = uilabel(app.UIFigure);
            app.PLOTSIMULATIONLabel.Text = '2) PLOT SIMULATION';
            app.TESTdobot_qButton = uibutton(app.UIFigure, 'push');
            app.TESTdobot_qButton.ButtonPushedFcn = createCallbackFcn(app, @TESTdobot_qButtonPushed, true);
            app.TESTdobot_qButton.Text = 'TEST dobot_q';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end